classdef eth2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        ImagesetPanel        matlab.ui.container.Panel
        ImagesetStatusLabel  matlab.ui.control.Label
        SelectFolderButton   matlab.ui.control.Button
        FolderSelectedLabel  matlab.ui.control.Label
        RootFolderLabel      matlab.ui.control.Label
    end

    
    properties (Access = private)
        Imageset % Imageset to be used 
    end
   

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Imageset = [];
            app.FolderSelectedLabel.Text = 'none selected';
            app.ImagesetStatusLabel.Text='';
        end

        % Button pushed function: SelectFolderButton
        function SelectFolderButtonPushed(app, event)
            % Select folder, then load
            selected = uigetdir([], 'Select Imageset root folder');
            if ischar(selected)
                try
                    app.Imageset = imageset(selected);
                    app.FolderSelectedLabel.Text = selected;
                    app.ImagesetStatusLabel.Text = sprintf('%d images loaded.', length(app.Imageset.Images));
                catch ME
                    fprintf('Error reading imageset from %s:\n%s\n', selected, getReport(ME));
                end 
            else
                fprintf('nothing selected\n');
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ImagesetPanel = uipanel(app.UIFigure);
            app.ImagesetPanel.Title = 'Imageset';
            app.RootFolderLabel = uilabel(app.ImagesetPanel);
            app.RootFolderLabel.Text = 'Root Folder:';
            app.FolderSelectedLabel = uilabel(app.ImagesetPanel);
            app.SelectFolderButton = uibutton(app.ImagesetPanel, 'push');
            app.SelectFolderButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFolderButtonPushed, true);
            app.SelectFolderButton.Text = 'Select Folder';
            app.ImagesetStatusLabel = uilabel(app.ImagesetPanel);
            app.ImagesetStatusLabel.Text = 'Label2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = eth2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end